classdef SPL_trend < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SPL_Trend_Simulation     matlab.ui.Figure
        Menu                     matlab.ui.container.Menu
        XPosEditFieldLabel       matlab.ui.control.Label
        XPos                     matlab.ui.control.NumericEditField
        MicLabel                 matlab.ui.control.Label
        MicSpinner               matlab.ui.control.Spinner
        StartSimulationButton    matlab.ui.control.Button
        readMicposButton         matlab.ui.control.Button
        ZlevelEditFieldLabel     matlab.ui.control.Label
        Zlevel                   matlab.ui.control.NumericEditField
        DirectionLabel           matlab.ui.control.Label
        Direction                matlab.ui.control.NumericEditField
        UIAxes                   matlab.ui.control.UIAxes
        RoomscaleEditFieldLabel  matlab.ui.control.Label
        Roomscale                matlab.ui.control.NumericEditField
        TextRoomscale            matlab.ui.control.TextArea
        GridscaleEditFieldLabel  matlab.ui.control.Label
        Gridscale                matlab.ui.control.NumericEditField
        TextGridscale            matlab.ui.control.TextArea
        TextZlevel               matlab.ui.control.TextArea
        Zlevel_display           matlab.ui.control.NumericEditField
        readSettings             matlab.ui.control.Button
        UpdateTrend              matlab.ui.control.Button
        IEMLogo                  matlab.ui.control.UIAxes
        TextMicDiscription       matlab.ui.control.TextArea
        YPosLabel                matlab.ui.control.Label
        YPos                     matlab.ui.control.NumericEditField
        ZPosLabel                matlab.ui.control.Label
        ZPos                     matlab.ui.control.NumericEditField
        Snapshot                 matlab.ui.control.Button
        deleteMicPosButton       matlab.ui.control.Button
        TextArea                 matlab.ui.control.TextArea
        TextArea_2               matlab.ui.control.TextArea
        TextArea_3               matlab.ui.control.TextArea
        TextArea_4               matlab.ui.control.TextArea
        TextZlevel_2             matlab.ui.control.TextArea
    end


    properties (Access = private)
        mic_x % mic x position
        mic_y % mic y position
        mic_z % mic z position
        mic_direction % mic direction
        roomscale % 
        gridscale %
        X
        Y
        Z
        SPL_total
    end

    methods (Access = private)
    
        function simulation(app)
            
            % mic_direction is seen like this
            % Y
            % 1   --------------------------------
            %    |     + ----> 0° <---- -         |
            %    |                                |
            %    |         \   |   /              |
            %    |          \  |  /               |
            %    |             x                  |
            %    |                                |
            % 0   --------------------------------
            % X  0                                1  
            %mic_direction = [30, 0, 0, -30];
            %mic_direction = [ 0, 0, 0];
            
            % folowing parameters can be changed:
            
            % roomscale and gridscale are read in the settings function
            % roomscale -> size of room (standard: 1x1x1)
            % gridscale -> points of meshgrid resolutions
            
            Z_level = (1 / app.gridscale) * app.roomscale;

            
            %% Simulation algorithm
            %  meshgrid to simulate source positions
            %  SPL is simulated for each microphone seperatley 
            
            % preallocation of meshgrid and vectors
            [app.X,app.Y,app.Z] = meshgrid(0:app.gridscale:1 * app.roomscale);
            SPL = app.X;
            SPL_cardioid_char = app.X;
            SPL_cardioid_char_total = app.X;
            alpha = app.X;
            SPL(:,:,:) = -100;
            SPL_cardioid_char(:,:,:) = -100;
            SPL_cardioid_char_total(:,:,:) =  -100;


            for Z_level = 1:1:Z_level 
                for n = 1:length(app.mic_x)
                    % calculation of distance between microphone and simulated source
                    % position -> radius
                    dx = app.X - app.mic_x(n);  
                    dy = app.Y - app.mic_y(n);
                    dz = app.Z - app.mic_z(n);
                    r_source = sqrt(dx.^2 + dy.^2 + dz.^2) + 1;
                    % calculation of angle between microphone and source position
                    for col = 1:length(app.X)
                        for row = 1:length(app.X)
                            if dx(row,col,Z_level) < 0 
                                alpha(row,col,Z_level) = 360 - acosd(dy(row,col,Z_level) / sqrt(dx(row,col,Z_level)^2 + dy(row,col,Z_level)^2));
                            else 
                                alpha(row,col,Z_level) =  acosd(dy(row,col,Z_level) / sqrt(dx(row,col,Z_level)^2 + dy(row,col,Z_level)^2));
                            end    
                        end
                    end
                    
                    % consideration of microphone direction
                    alpha = wrapTo360(alpha + app.mic_direction(n));
                    
                    % calculation of cardioid characterisation
                    for col = 1:length(app.X)
                        for row = 1:length(app.X)
                            if alpha(row,col,Z_level) == 360.0
                                alpha(row,col,Z_level) = 0;
                            end    
                            SPL_cardioid_char(row,col,Z_level) = 20*log10((1 + cosd(alpha(row,col,Z_level)))/2);
                        end
                    end
                    
                    SPL_cardioid_char_total = max(SPL_cardioid_char,SPL_cardioid_char_total);
                    SPL_radius = -20*log10(r_source);
                    SPL = max(SPL,SPL_radius);
                    app.SPL_total = SPL + SPL_cardioid_char_total;
                end

                % surfaceplot of SPL over X,Y and for current Z - level

  
            end
            updateTrend(app)
            app.StartSimulationButton.BackgroundColor = [0.96, 0.96, 0.96];
            
        end    
        
        function readMicPos(app)
            n = app.MicSpinner.Value;
            app.mic_x(n) = app.XPos.Value;
            app.mic_y(n) = app.YPos.Value;
            app.mic_z(n) = app.ZPos.Value;
            app.mic_direction(n) = app.Direction.Value;
            app.StartSimulationButton.Enable = 'on'
            app.StartSimulationButton.BackgroundColor = [1, 0, 0];
   
        end
        
        
        function readGridSettings(app)           
            % roomscale -> size of room (standard: 1x1x1)
            app.roomscale = app.Roomscale.Value; % 
            % gridscale -> points of meshgrid resolutions
            app.gridscale = 1 / app.Gridscale.Value; %
            app.Zlevel_display.Value = (1 / app.gridscale) * app.roomscale;
            app.readMicposButton.Enable = 'on';
            app.deleteMicPosButton.Enable = 'on';
            
        end
        
        
        function updateTrend(app)
             surf(app.UIAxes, app.X(:,:,app.Zlevel.Value),app.Y(:,:,app.Zlevel.Value),app.SPL_total(:,:,app.Zlevel.Value));
             title(app.UIAxes, ['SPL trend for Z - level ' num2str(app.Zlevel.Value)])
             xlabel(app.UIAxes, 'X')
             ylabel(app.UIAxes, 'Y')
             shading(app.UIAxes, 'interp')
             caxis(app.UIAxes,[-10 0])
             view(app.UIAxes,2)
             colorbar(app.UIAxes)
             vectarrow(app)
        end
        
        function vectarrow(app)
                % plot of microphone position and arrows for direction
                for n = 1:length(app.mic_x)
                    hold (app.UIAxes, 'on')
                    plot(app.UIAxes, app.mic_x(n),app.mic_y(n) ,'r*')
                    hold (app.UIAxes, 'on')
                    p0 = [app.mic_x(n) app.mic_y(n)];
                    p1 = [max(min((app.mic_x(n) - sind(app.mic_direction(n))*.1),app.roomscale),0) max(min((app.mic_y(n) + cosd(app.mic_direction(n))*.1),app.roomscale),0)];
                      if max(size(p0))==3
                          if max(size(p1))==3
                              x0 = p0(1);
                              y0 = p0(2);
                              z0 = p0(3);
                              x1 = p1(1);
                              y1 = p1(2);
                              z1 = p1(3);
                              plot3(app.UIAxes, [x0;x1],[y0;y1],[z0;z1],'r');   % Draw a line between p0 and p1
                              
                              p = p1-p0;
                              alpha = 0.1;  % Size of arrow head relative to the length of the vector
                              beta = 0.3;  % Width of the base of the arrow head relative to the length
                              
                              hu = [x1-alpha*(p(1)+beta*(p(2)+eps)); x1; x1-alpha*(p(1)-beta*(p(2)+eps))];
                              hv = [y1-alpha*(p(2)-beta*(p(1)+eps)); y1; y1-alpha*(p(2)+beta*(p(1)+eps))];
                              hw = [z1-alpha*p(3);z1;z1-alpha*p(3)];
                              
                              hold (app.UIAxes, 'on')
                              plot3(app.UIAxes, hu(:),hv(:),hw(:),'r')  % Plot arrow head
                              hold (app.UIAxes, 'off')
                          else
                              error('p0 and p1 must have the same dimension')
                        end
                      elseif max(size(p0))==2
                        if max(size(p1))==2
                          x0 = p0(1);
                          y0 = p0(2);
                          x1 = p1(1);
                          y1 = p1(2);
                          plot(app.UIAxes,[x0;x1],[y0;y1], 'r');   % Draw a line between p0 and p1
                          
                          p = p1-p0;
                          alpha = 0.1;  % Size of arrow head relative to the length of the vector
                          beta = 0.1;  % Width of the base of the arrow head relative to the length
                          
                          hu = [x1-alpha*(p(1)+beta*(p(2)+eps)); x1; x1-alpha*(p(1)-beta*(p(2)+eps))];
                          hv = [y1-alpha*(p(2)-beta*(p(1)+eps)); y1; y1-alpha*(p(2)+beta*(p(1)+eps))];
                          
                          hold (app.UIAxes, 'on')
                          plot(app.UIAxes,hu(:),hv(:),'r')  % Plot arrow head
                          hold (app.UIAxes, 'off')
                        else
                          error('p0 and p1 must have the same dimension')
                        end
                    else
                      error('this function only accepts 2D or 3D vector')
                      end
                end            
        end
        
        function importmenu(app)
            qitem = uimenu(app.Menu,'Text','Exit');
            qitem.MenuSelectedFcn = @Exit;
            function Exit(src,event)
                close(app.SPL_Trend_Simulation);
            end 
            
        end 
        
        function deleteMicPos(app)
            app.mic_x(app.MicSpinner.Value) = [];
            app.mic_y(app.MicSpinner.Value) = []; 
            app.mic_z(app.MicSpinner.Value) = []; 
            app.mic_direction(app.MicSpinner.Value) = [];
            app.StartSimulationButton.BackgroundColor = [1, 0, 0];
            
        end
        
        function resetGUI(app)
            % set GUI Values to zero
            app.MicSpinner.Value = 0;
            app.XPos.Value = 0;
            app.YPos.Value = 0;
            app.ZPos.Value = 0;
            app.Direction.Value = 0;
            % reset mic positions in Array
            app.mic_x = [];
            app.mic_y = []; 
            app.mic_z = []; 
            app.mic_direction = [];
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SPL_Trend_Simulation.Name = 'SPL Trend Simulation';
            imshow('iem.png','Parent',app.IEMLogo);
            importmenu(app)
            app.readMicposButton.Enable = 'off';
            app.deleteMicPosButton.Enable = 'off';
            app.StartSimulationButton.Enable = 'off'
            app.StartSimulationButton.BackgroundColor = [0.96, 0.96, 0.96];
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            simulation(app)
        end

        % Button pushed function: readMicposButton
        function readMicposButtonPushed(app, event)
            readMicPos(app)
        end

        % Button pushed function: readSettings
        function readSettingsButtonPushed(app, event)
            readGridSettings(app)
            resetGUI(app)
        end

        % Button pushed function: UpdateTrend
        function UpdateTrendButtonPushed(app, event)
            updateTrend(app)
        end

        % Button pushed function: Snapshot
        function SnapshotButtonPushed(app, event)
            h = figure(); 
            h.Visible = 'off';
            cb = colorbar(app.UIAxes);
            h = copyuiaxes(app.UIAxes, 'colorbar', cb)
        end

        % Button pushed function: deleteMicPosButton
        function deleteMicPosButtonButtonPushed(app, event)
            deleteMicPos(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SPL_Trend_Simulation = uifigure;
            app.SPL_Trend_Simulation.Name = 'UI Figure';
            app.Menu = uimenu(app.SPL_Trend_Simulation);
            app.Menu.Text = 'Menu';
            app.XPosEditFieldLabel = uilabel(app.SPL_Trend_Simulation);
            app.XPosEditFieldLabel.Text = 'X Pos';
            app.XPos = uieditfield(app.SPL_Trend_Simulation, 'numeric');
            app.XPos.Limits = [0 Inf];
            app.MicLabel = uilabel(app.SPL_Trend_Simulation);
            app.MicLabel.Text = '# Mic';
            app.MicSpinner = uispinner(app.SPL_Trend_Simulation);
            app.MicSpinner.Limits = [0 Inf];
            app.StartSimulationButton = uibutton(app.SPL_Trend_Simulation, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.readMicposButton = uibutton(app.SPL_Trend_Simulation, 'push');
            app.readMicposButton.ButtonPushedFcn = createCallbackFcn(app, @readMicposButtonPushed, true);
            app.readMicposButton.Text = 'read Mic pos';
            app.ZlevelEditFieldLabel = uilabel(app.SPL_Trend_Simulation);
            app.ZlevelEditFieldLabel.Text = 'Z level';
            app.Zlevel = uieditfield(app.SPL_Trend_Simulation, 'numeric');
            app.Zlevel.Value = 1;
            app.DirectionLabel = uilabel(app.SPL_Trend_Simulation);
            app.DirectionLabel.Text = 'Direction';
            app.Direction = uieditfield(app.SPL_Trend_Simulation, 'numeric');
            app.Direction.Limits = [-90 90];
            app.UIAxes = uiaxes(app.SPL_Trend_Simulation);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.RoomscaleEditFieldLabel = uilabel(app.SPL_Trend_Simulation);
            app.RoomscaleEditFieldLabel.Text = 'Roomscale';
            app.Roomscale = uieditfield(app.SPL_Trend_Simulation, 'numeric');
            app.Roomscale.Value = 1;
            app.TextRoomscale = uitextarea(app.SPL_Trend_Simulation);
            app.TextRoomscale.Editable = 'off';
            app.TextRoomscale.Value = {'Room scale - size of the room'; 'enter value e.g. 1 -> 1x1x1 '; ''};
            app.GridscaleEditFieldLabel = uilabel(app.SPL_Trend_Simulation);
            app.GridscaleEditFieldLabel.Text = 'Gridscale';
            app.Gridscale = uieditfield(app.SPL_Trend_Simulation, 'numeric');
            app.Gridscale.Limits = [0 1000];
            app.Gridscale.Value = 100;
            app.TextGridscale = uitextarea(app.SPL_Trend_Simulation);
            app.TextGridscale.Editable = 'off';
            app.TextGridscale.Value = {'Gridscale - amount of source points'; 'enter value e.g. 10, 100, 1000 '; ''};
            app.TextZlevel = uitextarea(app.SPL_Trend_Simulation);
            app.TextZlevel.Editable = 'off';
            app.TextZlevel.Value = {'Enter Z level between 1 and '};
            app.Zlevel_display = uieditfield(app.SPL_Trend_Simulation, 'numeric');
            app.Zlevel_display.Editable = 'off';
            app.readSettings = uibutton(app.SPL_Trend_Simulation, 'push');
            app.readSettings.ButtonPushedFcn = createCallbackFcn(app, @readSettingsButtonPushed, true);
            app.readSettings.Text = 'read Settings';
            app.UpdateTrend = uibutton(app.SPL_Trend_Simulation, 'push');
            app.UpdateTrend.ButtonPushedFcn = createCallbackFcn(app, @UpdateTrendButtonPushed, true);
            app.UpdateTrend.Text = 'Update Trend';
            app.IEMLogo = uiaxes(app.SPL_Trend_Simulation);
            app.IEMLogo.XLim = [0 1];
            app.IEMLogo.YLim = [0 1];
            app.IEMLogo.ZLim = [0 1];
            app.IEMLogo.CLim = [0 1];
            app.IEMLogo.XTick = [0 0.5 1];
            app.IEMLogo.YTick = [0 0.5 1];
            app.IEMLogo.ZTick = [0 0.5 1];
            app.TextMicDiscription = uitextarea(app.SPL_Trend_Simulation);
            app.TextMicDiscription.Editable = 'off';
            app.TextMicDiscription.Value = {' mic direction is seen like this'; ' Y'; ' 1   -----------------------'; '    |    + <---- 0° ----> -   |'; '    |            \   |   /            |'; '    |             \  |  /             |'; '    |                x                |'; ' 0   -----------------------'; ' X  0                                1  '; 'for confirm Microphone press'; '''read Mic pos'''};
            app.YPosLabel = uilabel(app.SPL_Trend_Simulation);
            app.YPosLabel.Text = 'Y Pos';
            app.YPos = uieditfield(app.SPL_Trend_Simulation, 'numeric');
            app.YPos.Limits = [0 Inf];
            app.ZPosLabel = uilabel(app.SPL_Trend_Simulation);
            app.ZPosLabel.Text = 'Z Pos';
            app.ZPos = uieditfield(app.SPL_Trend_Simulation, 'numeric');
            app.ZPos.Limits = [0 Inf];
            app.Snapshot = uibutton(app.SPL_Trend_Simulation, 'push');
            app.Snapshot.ButtonPushedFcn = createCallbackFcn(app, @SnapshotButtonPushed, true);
            app.Snapshot.Text = 'save Plot';
            app.deleteMicPosButton = uibutton(app.SPL_Trend_Simulation, 'push');
            app.deleteMicPosButton.ButtonPushedFcn = createCallbackFcn(app, @deleteMicPosButtonButtonPushed, true);
            app.deleteMicPosButton.Text = 'delete Mic';
            app.TextArea = uitextarea(app.SPL_Trend_Simulation);
            app.TextArea.Value = {'The algorithm helps to simulate the relative sound pressure level trend by given amount of microphones. '; 'Each point of the grid is simulating a sound source and it''s effect to the microphones. The microphones represents cardioid characteristics.'; ''; 'possible parameters to choose:'; '- Roomsize'; '- Gridscale'; '- Microphone position(x,y,z) and direction'; ''; 'Once you have simulated the room with microphones you can change the SPL Plot for each Z - level you want by "Update Trend". If you add or remove microphones or change room/gridscale start simulation again.'};
            app.TextArea_2 = uitextarea(app.SPL_Trend_Simulation);
            app.TextArea_2.Value = {'1. Step - Enter room and grid'};
            app.TextArea_3 = uitextarea(app.SPL_Trend_Simulation);
            app.TextArea_3.Value = {'2. Step - Enter microphones (X,Y,Z,..)'};
            app.TextArea_4 = uitextarea(app.SPL_Trend_Simulation);
            app.TextArea_4.Value = {'3. Step - Simulation'};
            app.TextZlevel_2 = uitextarea(app.SPL_Trend_Simulation);
            app.TextZlevel_2.Editable = 'off';
            app.TextZlevel_2.Value = {'for the trend'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = SPL_trend

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SPL_Trend_Simulation)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SPL_Trend_Simulation)
        end
    end
end